classdef CoastlinePrediction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Version10Label               matlab.ui.control.Label
        CoastlinePredictionLabel     matlab.ui.control.Label
        Panel_6                      matlab.ui.container.Panel
        SeaLevelmEditField           matlab.ui.control.NumericEditField
        SeaLevelmEditFieldLabel      matlab.ui.control.Label
        Panel_4                      matlab.ui.container.Panel
        ScenarioDropDown             matlab.ui.control.DropDown
        ScenarioDropDownLabel        matlab.ui.control.Label
        Panel_3                      matlab.ui.container.Panel
        LocationDropDown             matlab.ui.control.DropDown
        LocationDropDownLabel        matlab.ui.control.Label
        RegionDropDown               matlab.ui.control.DropDown
        RegionDropDownLabel          matlab.ui.control.Label
        DatasetButtonGroup           matlab.ui.container.ButtonGroup
        GivenSeaLevelButton          matlab.ui.control.RadioButton
        CopernicusButton             matlab.ui.control.RadioButton
        NOAA2022ReportButton         matlab.ui.control.RadioButton
        EarthdataPanel               matlab.ui.container.Panel
        OkButton                     matlab.ui.control.Button
        PasswordEditField            matlab.ui.control.EditField
        PasswordEditFieldLabel       matlab.ui.control.Label
        UsernameEditField            matlab.ui.control.EditField
        UsernameEditFieldLabel       matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        DownloadelevationdataButton  matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        SelectthisareaButton         matlab.ui.control.Button
        LimitsTextArea               matlab.ui.control.TextArea
        LimitsTextAreaLabel          matlab.ui.control.Label
        SLRprojectionButton          matlab.ui.control.Button
        SeaLevelProjectionsLabel     matlab.ui.control.Label
        ManualCheckBox               matlab.ui.control.CheckBox
        ResetMapButton               matlab.ui.control.Button
        MapPanel                     matlab.ui.container.Panel
        UpdatemapButton              matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        MinSpinner                   matlab.ui.control.Spinner
        MinSpinnerLabel              matlab.ui.control.Label
        MaxSpinner                   matlab.ui.control.Spinner
        MaxSpinnerLabel              matlab.ui.control.Label
        LatitudeLabel                matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        MaxSpinner_2                 matlab.ui.control.Spinner
        MaxSpinner_2Label            matlab.ui.control.Label
        MinSpinner_2                 matlab.ui.control.Spinner
        MinSpinner_2Label            matlab.ui.control.Label
        LongitudeLabel               matlab.ui.control.Label
        Panel_5                      matlab.ui.container.Panel
        YearDropDown                 matlab.ui.control.DropDown
        YearDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        gx
        LatitudeLimits
        LongitudeLimits
        TP        
        rasterURL
        rasterName
        coordinatesXY
        downloadCounter       
        opt
        rasterFile
        locations
        countriesList
        tableCountries
        year_cell_array
    end
    
    methods (Access = private)        
        
        function mapFunction(app) 

            % This function is responsible for extracting the latitude and
            % longitude boundaries in the Spinners. It also ensures that the
            % upper latitude and upper longitude in the spinners are greater.
            % And display the map based on the bowndaries.

            lat_start = app.MinSpinner.Value;
            lat_end = app.MaxSpinner.Value;
            lon_start = app.MinSpinner_2.Value;
            lon_end = app.MaxSpinner_2.Value; 
            if lat_end <= lat_start
                lat_end = lat_start + 1;
            end
            if lon_end <= lon_start
                lon_end = lon_start + 1;
            end            
            app.MaxSpinner.Value = lat_end;
            app.MaxSpinner_2.Value = lon_end;       
                        
            geolimits(app.gx,[lat_start lat_end],[lon_start lon_end])
            [app.LatitudeLimits,app.LongitudeLimits] = geolimits(app.gx);
            limitsCoordinates(app)            
        end              
        
        function plotMap(app)
            
            % This function is responsible for rendering the guide map image.
            
            app.gx = geoaxes(app.MapPanel,'Basemap','streets');  
            [app.LatitudeLimits,app.LongitudeLimits] = geolimits(app.gx);
            limitsCoordinates(app)
        end
                
        function limitsCoordinates(app)    
            
            % This function synchronizes the latitude and longitude boundaries
            % of the Spinners with those displayed in the "Limits" text box.

            app.LimitsTextArea.Value = ["Minimum Latitude = " + num2str(app.LatitudeLimits(1)); "Maximum Latitude = " + num2str(app.LatitudeLimits(2));
                "Minimum Longitude = " + num2str(app.LongitudeLimits(1));"Maximum Longitude = " + num2str(app.LongitudeLimits(2))];
            app.MinSpinner.Value = app.LatitudeLimits(1);
            app.MaxSpinner.Value = app.LatitudeLimits(2);
            app.MinSpinner_2.Value = app.LongitudeLimits(1);
            app.MaxSpinner_2.Value = app.LongitudeLimits(2);
        end
        
        function [bigRaster, Raux] = elevationRaster(app)

            % This function first checks if the raster files for the selected
            % area are already present in the local subfolder. If not, it informs
            % the user that there is missing data and that it needs to be
            % downloaded. These files are downloaded by pressing the "Download
            % elevation data" button. Once the data is complete, the "SLR
            % projection" button must be pressed again, resulting in a georeferenced
            % matrix with the elevations of the selected area.
            %
            % bigRaster: Variable that stores elevation data for the selected area. 
            % It is composed of data from all the rasters covering the area.
            % Raux: Georeference object for the bigRaster matrix.
            
            if app.RegionDropDown.Value == "U.S." % Procedure for 1/3 arc-second resolution in the United States territory.
                httpsURL = 'https://prd-tnm.s3.amazonaws.com/StagedProducts/Elevation/13/TIFF/current/';
                if app.LongitudeLimits(1) < -180 || app.LongitudeLimits(2) > 180
                    app.LongitudeLimits = wrapTo180(app.LongitudeLimits);
                end
                xCorners = floor(app.LongitudeLimits(1)) :1: floor(app.LongitudeLimits(2));
                yCorners = ceil(app.LatitudeLimits(1)) :1: ceil(app.LatitudeLimits(2));
                
                % Each raster image is named according to the coordinates
                % of the upper-left corner. The area covered by each .tif
                % image is one degree on each side in the NAD83 reference
                % system.

                app.rasterName = '';
                app.rasterURL = '';
                app.rasterFile = '';
                m = 1;
                j = 1;
                k = 0;                
                latlim = [100 0];
                lonlim = [1000 0];
                app.downloadCounter = 0;                
                elevationData = cell(1);  
                emptyMatrix = 0;
                R = cell(1);

                for i=1:length(xCorners)
                    l = 1;
                    for n=length(yCorners):-1:1
                        if yCorners(n) > 0 && xCorners(i) > 0
                            app.rasterName(j,:) = sprintf('USGS_13_n%02ie%03i.tif', yCorners(n), xCorners(i));
                            app.coordinatesXY = sprintf('n%02ie%03i/', yCorners(n), xCorners(i));
                        end
                        if yCorners(n) < 0 && xCorners(i) > 0
                            app.rasterName(j,:) = sprintf('USGS_13_s%02ie%03i.tif', abs(yCorners(n)), xCorners(i));
                            app.coordinatesXY = sprintf('s%02ie%03i/', abs(yCorners(n)), xCorners(i));
                        end
                        if yCorners(n) > 0 && xCorners(i) < 0
                            app.rasterName(j,:) = sprintf('USGS_13_n%02iw%03i.tif', yCorners(n), abs(xCorners(i)));
                            app.coordinatesXY = sprintf('n%02iw%03i/', yCorners(n), abs(xCorners(i)));
                        end
                        if yCorners(n) < 0 && xCorners(i) < 0
                            app.rasterName(j,:) = sprintf('USGS_13_s%02iw%03i.tif', abs(yCorners(n)), abs(xCorners(i)));
                            app.coordinatesXY = sprintf('s%02iw%03i/', abs(yCorners(n)), abs(xCorners(i)));
                        end

                        if isempty(dir(strcat('georasters\',app.rasterName(j,:)))) == 1
                            app.TextArea.Visible = "on";
                            app.TextArea.Value = 'There is missing elevation data that need to be downloaded from the USGS server.';
                            app.rasterURL(m,:) = strcat(httpsURL, app.coordinatesXY,app.rasterName(j,:));
                            app.rasterFile(m,:) = app.rasterName(j,:);
                            app.DownloadelevationdataButton.Enable = "on";
                            m = m + 1;              
                            app.downloadCounter = app.downloadCounter + 1;
                        else      % In the event that the files are already downloaded.                
                            s = dir(strcat('georasters\',app.rasterName(j,:)));

                            if s.bytes > 0          % A 0-byte .tif file is created even if there is no raster in the USGS database for the evaluated coordinate.                    
                                [elevationData{l,i},R{j}] = readgeoraster(strcat('georasters\',app.rasterName(j,:)));                            
                                rasterS = R{j}.RasterSize;
                                Raux = R{j};        % This will be the reference object for the final matrix.
                                
                                % The following conditions are used to determine the boundaries of the 
                                % area with available data.
                                if latlim(2) < abs(R{j}.LatitudeLimits(2))
                                    latlim(2) = R{j}.LatitudeLimits(2);
                                end
                                if latlim(1) > abs(R{j}.LatitudeLimits(1))
                                    latlim(1) = R{j}.LatitudeLimits(1);
                                end
                                if lonlim(2) < abs(R{j}.LongitudeLimits(2))
                                    lonlim(2) = R{j}.LongitudeLimits(2);
                                end
                                if lonlim(1) > abs(R{j}.LongitudeLimits(1))
                                    lonlim(1) = R{j}.LongitudeLimits(1);
                                end                                
                            else       % For 0-byte files                                                 
                                elevationData{l,i} = NaN;
                                R{j} = NaN;
                                k = k + 1;
                                emptyMatrix(k) = j; % This variable storage the location of empty cells.
                            end                                                                        
                            % These are auxiliary variables used to eliminate external rows or columns
                            % that are completely empty (lacking data).
                            if n == length(yCorners)
                                initialRow(i) = j;   
                            end
                            if n == 1
                                finalRow(i) = j; 
                            end
                            if i == length(xCorners)
                                lastColumn(n) = j;
                            end
                        end
                        j = j + 1;
                        l = l + 1;
                    end
                end
                
                if m == 1
                    % Once the reading and storage of raster data is complete, the next step 
                    % involves removing the external rows and columns that are entirely empty.
                    % Subsequently, the remaining empty cells are filled with the number 
                    % -999999, maintaining the same dimensions as the rasters.
                    numberRows = size(elevationData,1);
                    numberColumns = size(elevationData,2);
                    
                    if sum(ismember(initialRow,emptyMatrix)) == length(initialRow) % Empty first row of elevationData
                        emptyMatrix(find(initialRow)) = [];       % Deletes location register of empty cells
                        k = k - length(initialRow);
                        numberRows = numberRows - 1;
                    end
                    if sum(ismember(finalRow,emptyMatrix)) == length(finalRow) % Empty last row of elevationData
                        emptyMatrix(find(finalRow)) = [];
                        k = k - length(finalRow);
                        numberRows = numberRows - 1;
                    end
                    lengthColumn = size(elevationData,1);
                    if sum(ismember(1:lengthColumn,emptyMatrix)) == lengthColumn  % Empty first column of elevationData
                        emptyMatrix(find(1:lengthColumn)) = [];
                        k = k - lengthColumn;
                        numberColumns = numberColumns - 1;
                    end
                    if sum(ismember(lastColumn,emptyMatrix)) == lengthColumn    % Empty last column of elevationData
                        emptyMatrix(find(lastColumn)) = [];
                        k = k - lengthColumn;
                        numberColumns = numberColumns - 1;
                    end
                    if k > 0
                        fillMatrix = single(linspace(-999999,-999999,rasterS(1)*rasterS(2)));
                        fillMatrix = reshape(fillMatrix,rasterS(1),rasterS(2));
                        for i = 1:k
                            elevationData{emptyMatrix(i)} = fillMatrix;
                        end
                    end                    
                    
                    % The raster images overlap by 12 rows and 12 columns, so it is necessary 
                    % to first remove this overlap. The data is arranged in such a way that the
                    % cells have equal horizontal dimensions to facilitate vertical concatenation.
                    bigRaster = cell(1);
                    j = 1;
                    for i=1:numberColumns
                        for n=1:numberRows
                            v = find(emptyMatrix == j);
                            
                            if n < numberRows && isempty(v) == 1     % If cell contains data               
                                elevationData{n+1,i}(1:12,:) = [];   % Deletes first 12 rows of the next cell in the same column                             
                            end

                            if n < numberRows && isempty(v) == 0            % If cell is empty
                                elevationData{n,i}(end-11:end,:) = elevationData{n+1,i}(1:12,:);      % Copy first 12 rows of the next cell in the same column                          
                                elevationData{n+1,i}(1:12,:) = [];   % Deletes first 12 rows of the next cell in the same column
                            end                          
                            
                            if i < numberColumns && isempty(v) == 1         % If cell contains data
                                elevationData{n,i+1}(:,1:12) = [];          % Deletes first 12 columns of the next cell in the same row
                            end  

                            if i < numberColumns && isempty(v) == 0         % If cell is empty
                                % Fills the last 12 columns with the data from the first 12 columns of the next cell in the same row
                                elevationData{n,i}(:,end-11:end) = elevationData{n,i+1}(end - size(elevationData{n,i},1) + 1:end,1:12);                                
                                elevationData{n,i+1}(:,1:12) = [];          % Deletes first 12 columns of the next cell in the same row
                            end                              
                            if n == 1
                                bigRaster{i} = elevationData{n,i};
                            end
                            if n > 1
                                bigRaster{i} = [bigRaster{i}; elevationData{n,i}];
                            end
                            j = j + 1;                            
                        end                          
                    end                    
                    
                    bigRaster = cell2mat(bigRaster);    % Horizontal concatenation and one big matrix with elevation data.
                    Raux.RasterSize = size(bigRaster);
                    Raux.LatitudeLimits = latlim;
                    Raux.LongitudeLimits = lonlim;
                    % Data outside the selected area is excluded.
                    [bigRaster, Raux] = geocrop(bigRaster, Raux, app.LatitudeLimits, app.LongitudeLimits);
                    bigRaster(bigRaster == -999999) = NaN;
                    clear elevationData                    
                else
                    bigRaster = [];
                    Raux = [];                    
                end  

            else   % Global Region
                httpsURL = 'https://e4ftl01.cr.usgs.gov//DP109/SRTM/SRTMGL1.003/2000.02.11/';
                if app.LongitudeLimits(1) < -180 || app.LongitudeLimits(2) > 180
                    app.LongitudeLimits = wrapTo180(app.LongitudeLimits);
                end
                xCorners = floor(app.LongitudeLimits(1)) :1: floor(app.LongitudeLimits(2));
                yCorners = floor(app.LatitudeLimits(1)) :1: floor(app.LatitudeLimits(2));
                
                % Each raster image is named according to the coordinates
                % of the bottom-left corner. The area covered by each .tif
                % image is one degree on each side in the wgs84 reference
                % system.

                app.rasterName = '';
                app.rasterURL = '';
                app.rasterFile = '';
                m = 0;
                j = 1;
                k = 0;
                latlim = [100 0];
                lonlim = [1000 0];
                app.downloadCounter = 0;
                elevationData = cell(1);
                emptyMatrix = 0;
                R = cell(1);  

                for i=1:length(xCorners)
                    l = 1;
                    for n=length(yCorners):-1:1
                        if yCorners(n) >= 0 && xCorners(i) >= 0
                            app.rasterName(j,:) = sprintf('N%02dE%03d.SRTMGL1.hgt.zip', yCorners(n), xCorners(i));
                            app.coordinatesXY = sprintf('N%02iE%03i', yCorners(n), xCorners(i));
                        end
                        if yCorners(n) < 0 && xCorners(i) >= 0
                            app.rasterName(j,:) = sprintf('S%02dE%03d.SRTMGL1.hgt.zip', abs(yCorners(n)), xCorners(i));
                            app.coordinatesXY = sprintf('S%02dE%03d', abs(yCorners(n)), xCorners(i));
                        end
                        if yCorners(n) >= 0 && xCorners(i) < 0
                            app.rasterName(j,:) = sprintf('N%02dW%03d.SRTMGL1.hgt.zip', yCorners(n), abs(xCorners(i)));
                            app.coordinatesXY = sprintf('N%02dW%03d', yCorners(n), abs(xCorners(i)));
                        end
                        if yCorners(n) < 0 && xCorners(i) < 0
                            app.rasterName(j,:) = sprintf('S%02dW%03d.SRTMGL1.hgt.zip', abs(yCorners(n)), abs(xCorners(i)));
                            app.coordinatesXY = sprintf('S%02dW%03d', abs(yCorners(n)), abs(xCorners(i)));
                        end
                                                
                        if isempty(dir(strcat('georasters1\',app.rasterName(j,:)))) == 1
                            % The raster is checked against the list of non-existent data in the Earthdata database.
                            % This list is created based on previous downloads.
                            % In the event that the raster is found in the list, an empty cell is obtained.
                            if exist("noData.mat","file")  
                                noData = open("noData.mat");
                                noData = noData.noData;                                
                                if ismember(app.rasterName(j,:),noData)
                                    elevationData{l,i} = NaN;
                                    R{j} = NaN;
                                    k = k + 1;
                                    emptyMatrix(k) = j; % This variable storage the location of empty cells.
                                else
                                    m = m + 1;
                                    app.TextArea.Visible = "on";
                                    app.TextArea.Value = 'There is missing elevation data that needs to be downloaded. You must have an EARTHDATA account to be able to download it. Here is the website address where you can create your account: earthdata.nasa.gov.';
                                    app.rasterURL(m,:) = strcat(httpsURL, app.rasterName(j,:));
                                    app.rasterFile(m,:) = app.rasterName(j,:);
                                    app.EarthdataPanel.Visible = "on";
                                    app.downloadCounter = app.downloadCounter + 1;
                                end
                            else
                                m = m + 1;
                                app.TextArea.Visible = "on";
                                app.TextArea.Value = 'There is missing elevation data that needs to be downloaded. You must have an EARTHDATA account to be able to download it. Here is the website address where you can create your account: earthdata.nasa.gov.';
                                app.rasterURL(m,:) = strcat(httpsURL, app.rasterName(j,:));
                                app.rasterFile(m,:) = app.rasterName(j,:);
                                app.EarthdataPanel.Visible = "on";
                                app.downloadCounter = app.downloadCounter + 1;
                            end                            
                        else                       
                                                          
                            [elevationData{l,i},R{j}] = readgeoraster(strcat('georasters1\',app.rasterName(j,:)));
                            rasterS = R{j}.RasterSize;
                            Raux = R{j};        % This will be the reference object for the final matrix.

                            % The following conditions are used to determine the boundaries of the
                            % area with available data.
                            if latlim(2) < abs(R{j}.LatitudeLimits(2))
                                latlim(2) = R{j}.LatitudeLimits(2);
                            end
                            if latlim(1) > abs(R{j}.LatitudeLimits(1))
                                latlim(1) = R{j}.LatitudeLimits(1);
                            end
                            if lonlim(2) < abs(R{j}.LongitudeLimits(2))
                                lonlim(2) = R{j}.LongitudeLimits(2);
                            end
                            if lonlim(1) > abs(R{j}.LongitudeLimits(1))
                                lonlim(1) = R{j}.LongitudeLimits(1);
                            end                                                                                   
                             
                        end
                        % These are auxiliary variables used to eliminate external rows or columns
                        % that are completely empty (lacking data).
                        if n == length(yCorners)
                            initialRow(i) = j;
                        end
                        if n == 1
                            finalRow(i) = j;
                        end
                        if i == length(xCorners)
                            lastColumn(n) = j;
                        end
                        j = j + 1;
                        l = l + 1;
                    end
                end
                
                if m == 0
                    % Once the reading and storage of raster data is complete, the next step 
                    % involves removing the external rows and columns that are entirely empty.
                    % Subsequently, the remaining empty cells are filled with the number 
                    % -32768, maintaining the same dimensions as the rasters.
                    numberRows = size(elevationData,1);
                    numberColumns = size(elevationData,2);
                    
                    if sum(ismember(initialRow,emptyMatrix)) == length(initialRow)
                        emptyMatrix(find(initialRow)) = [];
                        k = k - length(initialRow);
                        numberRows = numberRows - 1;
                    end
                    if sum(ismember(finalRow,emptyMatrix)) == length(finalRow)
                        emptyMatrix(find(finalRow)) = [];
                        k = k - length(finalRow);
                        numberRows = numberRows - 1;
                    end
                    lengthColumn = size(elevationData,1);
                    if sum(ismember(1:lengthColumn,emptyMatrix)) == lengthColumn
                        emptyMatrix(find(1:lengthColumn)) = [];
                        k = k - lengthColumn;
                        numberColumns = numberColumns - 1;
                    end
                    if sum(ismember(lastColumn,emptyMatrix)) == lengthColumn
                        emptyMatrix(find(lastColumn)) = [];
                        k = k - lengthColumn;
                        numberColumns = numberColumns - 1;
                    end
                    if k > 0
                        fillMatrix = single(linspace(-32768,-32768,rasterS(1)*rasterS(2)));
                        fillMatrix = reshape(fillMatrix,rasterS(1),rasterS(2));
                        for i = 1:k
                            elevationData{emptyMatrix(i)} = fillMatrix;
                        end
                    end                    
                    
                    % The raster images overlap by 1 row and 1 column, so it is necessary 
                    % to first remove this overlap. The data is arranged in such a way that the
                    % cells have equal horizontal dimensions to facilitate vertical concatenation.
                    bigRaster = cell(1);
                    j = 1;
                    for i=1:numberColumns
                        for n=1:numberRows
                            v = find(emptyMatrix == j);
                            
                            if n < numberRows && isempty(v) == 1    % If cell contains data                 
                                elevationData{n+1,i}(1,:) = [];     % Deletes first row of the next cell in the same column                          
                            end

                            if n < numberRows && isempty(v) == 0    % If cell is empty
                                elevationData{n,i}(end,:) = elevationData{n+1,i}(1,:);     % Copy first row of the next cell in the same column
                                elevationData{n+1,i}(1,:) = [];     % Deletes first row of the next cell in the same column
                            end                          
                            
                            if i < numberColumns && isempty(v) == 1     % If cell contains data
                                elevationData{n,i+1}(:,1) = [];         % Deletes first column of the next cell in the same row
                            end  

                            if i < numberColumns && isempty(v) == 0     % If cell is empty                                
                                % Fills the last column with the data from the first column of the next cell
                                elevationData{n,i}(:,end) = elevationData{n,i+1}(end - size(elevationData{n,i},1) + 1,1);      
                                elevationData{n,i+1}(:,1) = [];         % Deletes first column of the next cell in the same row
                            end                              
                            if n == 1
                                bigRaster{i} = elevationData{n,i};
                            end
                            if n > 1
                                bigRaster{i} = [bigRaster{i}; elevationData{n,i}];
                            end
                            j = j + 1;                            
                        end                          
                    end                    
                    
                    bigRaster = cell2mat(bigRaster);    % Horizontal concatenation and one big matrix with elevation data.
                    Raux.RasterSize = size(bigRaster);
                    Raux.LatitudeLimits = latlim;
                    Raux.LongitudeLimits = lonlim;                    
                   
                    % Data outside the selected area is excluded.
                    [bigRaster, Raux] = geocrop(bigRaster, Raux, app.LatitudeLimits, app.LongitudeLimits);
                    bigRaster(bigRaster == -32768) = NaN;
                    bigRaster = single(bigRaster);
                    clear elevationData
                else
                    bigRaster = [];
                    Raux = [];                    
                end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plotMap(app)    % Shows the initial map         
            
            % Reading the mean sea level projections downloaded from the 
            % NOAA Digital Coast website.
            tableProjections = readtable('SLR_TF U.S. Sea Level Projections.csv');

            % Remove metadata
            app.TP = tableProjections(12:end,:);

            % Change variable names of the RSL year columns
            year = 2020;
            for i=15:28
                app.TP = renamevars(app.TP, "Var" + num2str(i), num2str(year));
                year = year + 10;
            end
            
            % Table for the locations name
            USTable = tableProjections(27:2576,:);

            % Take location colum with unique values only
            app.locations = table2array(unique(USTable(:,1)));            
            scenarios = table2array(unique(app.TP(:,10)));
            
            app.ScenarioDropDown.Items = scenarios;

            % Drop down years
            years = string(2020:10:2150);
            app.year_cell_array = cellstr(years);           
             
            
            % Creating list of countries
            app.tableCountries = readtable("countries.csv");
            app.countriesList = table2array(unique(app.tableCountries(:,4)));           
            app.countriesList = ["..."; app.countriesList];
        end

        % Button pushed function: UpdatemapButton
        function UpdatemapButtonPushed(app, event)
            % This button updates the displayed map based on the
            % coordinates entered in the Spinners.
            mapFunction(app);            
            
        end

        % Button pushed function: ResetMapButton
        function ResetMapButtonPushed(app, event)
            % This button resets the map to the global view.
            app.MinSpinner.Value = -90;
            app.MaxSpinner.Value = 90;
            app.MinSpinner_2.Value = -180;
            app.MaxSpinner_2.Value = 180;
            mapFunction(app);
            app.Label.Text = '';
        end

        % Value changed function: ManualCheckBox
        function ManualCheckBoxValueChanged(app, event)
            % This checkbox enables or disables the option to manually
            % enter latitude and longitude boundaries in the Spinners.
            % After entering the boundaries, the map should be updated
            % using the "Update map" button.
            value = app.ManualCheckBox.Value;
            if value == 1
                app.Panel.Enable = "on";  
                app.Panel_2.Enable = "on";
                app.UpdatemapButton.Enable = "on";
                app.ResetMapButton.Enable = "on";
                
            else
                app.Panel.Enable = "off";
                app.Panel_2.Enable = "off"; 
                app.UpdatemapButton.Enable = "off";
                app.ResetMapButton.Enable = "off";
                
            end
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            % This dropdown menu allows you to choose whether to use the
            % exclusive United States database or the global database. The
            % former utilizes elevations from USGS with a resolution of 1/3
            % arcseconds, while the global option utilizes data from the
            % Shuttle Radar Topography Mission (SRTM) with a resolution of
            % 1 arcsecond. The 1/3 arcseconds database is only available
            % for the United States territory.

            value = app.RegionDropDown.Value;
            if value == "U.S."
                app.Panel_3.Enable = "on";
                if app.DatasetButtonGroup.SelectedObject == app.NOAA2022ReportButton                
                    app.Panel_4.Enable = "on";
                    app.Panel_5.Enable = "on";
                    app.YearDropDown.Items = app.year_cell_array;                    
                end
                if app.DatasetButtonGroup.SelectedObject == app.CopernicusButton
                    app.Panel_5.Enable = "on";
                    app.Panel_6.Visible = "off";
                end
                if app.DatasetButtonGroup.SelectedObject == app.GivenSeaLevelButton
                    app.Panel_5.Enable = "off";
                    app.Panel_6.Visible = "on";                
                end
                app.SelectthisareaButton.Enable = "on";
                app.LocationDropDown.Items = app.locations;                
                LocationDropDownValueChanged(app, event)  
                app.EarthdataPanel.Visible = "off";
            elseif value == "Global"
                app.Panel_3.Enable = "on";
                if app.DatasetButtonGroup.SelectedObject == app.NOAA2022ReportButton     
                    app.Panel_4.Enable = "on";
                    app.Panel_5.Enable = "on";
                    app.YearDropDown.Items = app.year_cell_array;                
                end
                if app.DatasetButtonGroup.SelectedObject == app.CopernicusButton
                    app.Panel_5.Enable = "on";
                    app.Panel_6.Visible = "off";
                end
                if app.DatasetButtonGroup.SelectedObject == app.GivenSeaLevelButton
                    app.Panel_5.Enable = "off";
                    app.Panel_6.Visible = "on";
                end
                app.LocationDropDown.Items = app.countriesList;
                % delete(app.gx)
                % plotMap(app) 
                app.SelectthisareaButton.Enable = "on";
                app.ManualCheckBox.Value = 1;
                ManualCheckBoxValueChanged(app,event)                
            else
                app.Panel_3.Enable = "off";
                app.Panel_4.Enable = "off";
                app.Panel_5.Enable = "off";
                app.Panel_6.Visible = "off";
                delete(app.gx)
                plotMap(app)
                app.SLRprojectionButton.Enable = "off";
                app.SelectthisareaButton.Enable = "off";
                app.ManualCheckBox.Value = 0;
                ManualCheckBoxValueChanged(app,event)
                app.EarthdataPanel.Visible = "off";
            end
            app.Label.Text = '';
        end

        % Button pushed function: SLRprojectionButton
        function SLRprojectionButtonPushed(app, event)
            % The function of the SLR projection button is divided into three parts corresponding to the available datasets. 
            % Each part will be executed based on the chosen dataset and region.
            
            if app.RegionDropDown.Value == "U.S." && app.DatasetButtonGroup.SelectedObject == app.NOAA2022ReportButton           

                % The first step is to search for sea level projection data within a
                % one-degree radius of the selected area. If no data is found, 
                % subsequent calculations will not proceed.
                idx1 = any(app.TP{:,6}>=app.LatitudeLimits(1) - 1 & app.TP{:,6}<=app.LatitudeLimits(2) + 1 ,2);
                idx2 = any(app.TP{:,7}>=app.LongitudeLimits(1) - 1 & app.TP{:,7}<=app.LongitudeLimits(2) + 1 ,2);
                idx = find(idx1==1 & idx2==1);
                
                if isempty(idx)
                    app.Label.Text = 'There is not sea level data for this area. Try Global region.';
                else
                    % idxScenario: Index of locations in the table of projections for the chosen scenario.
                    % InterpolationZone: Index of points with data located within the search zone that will 
                    % be interpolated to create a raster. 
                    % idxRSL: Index of column containing the projected year.
                    idxScenario = find(strcmp(app.TP.Var10,app.ScenarioDropDown.Value));
                    InterpolationZone = intersect(idx,idxScenario);
                    idxRSL = find(strcmp(app.TP.Properties.VariableNames,app.YearDropDown.Value));                    
                    
                    % x, y: Extract the coordinates of the points with data.
                    % meanSLR: Extract the sea level projected based on the
                    % selected year.
                    y = table2array(app.TP(InterpolationZone,6));
                    x = table2array(app.TP(InterpolationZone,7));
                    RSL = table2array(app.TP(InterpolationZone,idxRSL));                                                                           
                                      
                    [bigRaster, Raux] = elevationRaster(app);                    
                    
                    if ~isempty(bigRaster) && ~isempty(Raux)
                        
                        latlimDEM = Raux.LatitudeLimits;
                        lonlimDEM = Raux.LongitudeLimits;

                        % Construction of the grid of interpolated sea level data within the selected area.
                        horizontal = linspace(lonlimDEM(1),lonlimDEM(2),size(bigRaster,2));
                        vertical = linspace(latlimDEM(1),latlimDEM(2),size(bigRaster,1));
                        [xq,yq] = meshgrid(horizontal,vertical);
                        yq = flip(yq);
                        vq = griddata(x,y,RSL,xq,yq,'cubic');
                        vq = vq./100;   % cm to meters                        
                        
                        % bigRaster(bigRaster < vq) = NaN;
                        % vq(bigRaster > vq & bigRaster > 0) = NaN;

                        % Elevation minus Rising Sea Level
                        bigRaster(bigRaster == 0) = NaN;
                        bigRaster = bigRaster - vq;
                        bottomR = min(bigRaster,[],"all");
                        
                        %Creation of background
                        [xq,yq] = meshgrid(app.LongitudeLimits,app.LatitudeLimits);
                        yq = flip(yq);
                        if bottomR >= 0
                            sea = [-1 -1; -1 -1];
                        else
                            sea = double([(bottomR - 0.5), (bottomR - 0.5); (bottomR - 0.5), (bottomR - 0.5)]);
                        end
                        vq = griddata(app.LongitudeLimits,app.LatitudeLimits,sea,xq,yq);
                        
                        % Plotting
                        plotTitle = "Coastline prediction for year " + app.YearDropDown.Value;
                        worldmap(app.LatitudeLimits,app.LongitudeLimits)
                        geoshow(yq,xq,vq,'DisplayType','surface');
                        title(plotTitle)                         
                        hold on
                        geoshow(bigRaster,Raux,"DisplayType","surface");
                        demcmap(bigRaster)
                        cb = colorbar('eastoutside');
                        cb.Label.String = "Meters above sea level";
                        hold off
                 
                        %clear xq yq                   
                        
                    end
                end

            elseif app.RegionDropDown.Value == "Global"  && app.DatasetButtonGroup.SelectedObject == app.NOAA2022ReportButton
                
                % For the NOAA dataset, the Global region corresponds to global sea levels. Therefore, it is only necessary 
                % to find the value according to the scenario and the year.

                idx = find(strcmp(app.TP.MeanSeaLevelDatasetFor_GlobalAndRegionalSeaLevelRiseScenariosFo,"GMSL"));
                idxScenario = find(strcmp(app.TP.Var10,app.ScenarioDropDown.Value));
                idx2 = intersect(idx,idxScenario);
                idxRSL = find(strcmp(app.TP.Properties.VariableNames,app.YearDropDown.Value));
                RSL = table2array(app.TP(idx2,idxRSL));
                RSL = RSL ./ 100; %cm to meters
  
                [bigRaster, Raux] = elevationRaster(app);                

                if ~isempty(bigRaster) && ~isempty(Raux)
                                                  
                    %bigRaster(bigRaster < RSL) = NaN;
                    bigRaster(bigRaster == 0) = NaN;
                    bigRaster = bigRaster - RSL;
                    bottomR = min(bigRaster,[],"all");

                    % Creation of background
                    [xq,yq] = meshgrid(app.LongitudeLimits,app.LatitudeLimits);
                    yq = flip(yq);
                    if bottomR >= 0
                        sea = [-1 -1; -1 -1];
                    else
                        sea = double([(bottomR - 0.5), (bottomR - 0.5); (bottomR - 0.5), (bottomR - 0.5)]);
                    end
                    vq = griddata(app.LongitudeLimits,app.LatitudeLimits,sea,xq,yq);
                    
                    % Plot
                    plotTitle = "Coastline prediction for year " + app.YearDropDown.Value;
                    worldmap(app.LatitudeLimits,app.LongitudeLimits)
                    geoshow(yq,xq,vq,'DisplayType','surface');
                    title(plotTitle)
                    hold on
                    geoshow(bigRaster,Raux,"DisplayType","surface");
                    demcmap(bigRaster)
                    cb = colorbar('eastoutside');
                    cb.Label.String = "Meters above sea level";
                    hold off                                       
                    
                end
                
            end
            
            if app.DatasetButtonGroup.SelectedObject == app.CopernicusButton
                                
                % Reading NetCDF Files
                copernicusSL = ncread("dataset-sis-water-level-change-timeseries-cmip6\future_msl_" + app.YearDropDown.Value + "_01_v1.nc","mean_sea_level");
                x_coordinates = ncread("dataset-sis-water-level-change-timeseries-cmip6\future_msl_" + app.YearDropDown.Value + "_01_v1.nc","station_x_coordinate");
                y_coordinates = ncread("dataset-sis-water-level-change-timeseries-cmip6\future_msl_" + app.YearDropDown.Value + "_01_v1.nc","station_y_coordinate");

                % Searching for data within a one-degree radius of the selected area.
                idx1 = any(y_coordinates>=app.LatitudeLimits(1) - 1 & y_coordinates<=app.LatitudeLimits(2) + 1,2);
                idx2 = any(x_coordinates>=app.LongitudeLimits(1) - 1 & x_coordinates<=app.LongitudeLimits(2) + 1,2);
                idx = find(idx1 & idx2);
                
                if isempty(idx)
                    app.Label.Text = 'There is not sea level values for this area.';
                else
                    
                    x = x_coordinates(idx);
                    y = y_coordinates(idx);
                    meanSL = copernicusSL(idx);

                    [bigRaster, Raux] = elevationRaster(app);

                    if ~isempty(bigRaster) && ~isempty(Raux)
                        
                        latlimDEM = Raux.LatitudeLimits;
                        lonlimDEM = Raux.LongitudeLimits;

                        % Construction of the grid of interpolated sea level data within the selected area.
                        horizontal = linspace(lonlimDEM(1),lonlimDEM(2),size(bigRaster,2));
                        vertical = linspace(latlimDEM(1),latlimDEM(2),size(bigRaster,1));
                        [xq,yq] = meshgrid(horizontal,vertical);
                        yq = flip(yq);
                        vq = griddata(x,y,meanSL,xq,yq,'cubic');
                                                
                        % bigRaster(bigRaster < vq) = NaN;
                        % vq(bigRaster > vq & bigRaster > 0) = NaN;
                        bigRaster(bigRaster == 0) = NaN;
                        bigRaster = bigRaster - vq;
                        bottomR = min(bigRaster,[],"all");

                        % Creation of background
                        [xq,yq] = meshgrid(app.LongitudeLimits,app.LatitudeLimits);
                        yq = flip(yq);
                        if bottomR >= 0
                            sea = [-1 -1; -1 -1];
                        else
                            sea = double([(bottomR - 0.5), (bottomR - 0.5); (bottomR - 0.5), (bottomR - 0.5)]);
                        end                        
                        vq = griddata(app.LongitudeLimits,app.LatitudeLimits,sea,xq,yq);
                        
                        % Plot
                        plotTitle = "Coastline prediction for year " + app.YearDropDown.Value;
                        worldmap(app.LatitudeLimits,app.LongitudeLimits)
                        geoshow(yq,xq,vq,'DisplayType','surface');
                        title(plotTitle)
                        hold on
                        geoshow(bigRaster,Raux,"DisplayType","surface");
                        demcmap(bigRaster)
                        cb = colorbar('eastoutside');
                        cb.Label.String = "Meters above sea level";
                        hold off
                 
                        %clear xq yq
                    
                    end
                end
            end

            if app.DatasetButtonGroup.SelectedObject == app.GivenSeaLevelButton
                
                gSeaLevel = app.SeaLevelmEditField.Value;
                [bigRaster, Raux] = elevationRaster(app);
                if ~isempty(bigRaster) && ~isempty(Raux)
                    
                    %bigRaster(bigRaster < gSeaLevel) = NaN;
                    bigRaster(bigRaster == 0) = NaN;
                    bigRaster = bigRaster - gSeaLevel; 
                    bottomR = min(bigRaster,[],"all");
                    
                    % Creation of background
                    [xq,yq] = meshgrid(app.LongitudeLimits,app.LatitudeLimits);
                    yq = flip(yq);
                    if bottomR >= 0                        
                        sea = [-1 -1; -1 -1];
                    else
                        sea = double([(bottomR - 0.5), (bottomR - 0.5); (bottomR - 0.5), (bottomR - 0.5)]);
                    end
                    vq = griddata(app.LongitudeLimits,app.LatitudeLimits,sea,xq,yq);
                    
                    % Plot
                    plotTitle = "Coastline for sea level " + string(gSeaLevel) + "m";
                    worldmap(app.LatitudeLimits,app.LongitudeLimits)
                    geoshow(yq,xq,vq,'DisplayType','surface');                    
                    title(plotTitle)
                    hold on
                    geoshow(bigRaster,Raux,"DisplayType","surface");
                    demcmap(bigRaster)
                    cb = colorbar('eastoutside');
                    cb.Label.String = "Meters above sea level";
                    hold off         

                end
            end

            app.SLRprojectionButton.Enable = "off";
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            % The location dropdown menu takes the corresponding
            % coordinates from the table and updates the map with a
            % one-degree range on each side.
            
            value = app.LocationDropDown.Value;
            if app.RegionDropDown.Value == "U.S."
                idx1 = find(strcmp(app.TP.MeanSeaLevelDatasetFor_GlobalAndRegionalSeaLevelRiseScenariosFo,value));
                centerX = table2array(unique(app.TP(idx1,7)));
                centerY = table2array(unique(app.TP(idx1,6)));
                delete(app.gx)
                plotMap(app)
                geolimits(app.gx,[(centerY - 0.5) (centerY + 0.5)],[(centerX - 0.5) (centerX + 0.5)]);
            elseif app.RegionDropDown.Value == "Global" && value ~= "..."
                idx1 = find(strcmp(app.tableCountries.name,value));
                centerX = table2array(unique(app.tableCountries(idx1,3)));
                centerY = table2array(unique(app.tableCountries(idx1,2)));                
                delete(app.gx)
                plotMap(app)
                geolimits(app.gx,[(centerY - 6) (centerY + 6)],[(centerX - 6) (centerX + 6)]);
            end
            
            [app.LatitudeLimits,app.LongitudeLimits] = geolimits(app.gx);
            limitsCoordinates(app)
            app.TextArea.Visible = "off";
            app.TextArea.Value = '';
            app.Label.Text = '';
        end

        % Button pushed function: SelectthisareaButton
        function SelectthisareaButtonPushed(app, event)
            % The area selection button updates the latitude and longitude
            % boundaries according to the displayed zone. This button was
            % added to allow manual adjustment of the area of interest.
            [app.LatitudeLimits,app.LongitudeLimits] = geolimits(app.gx);
            limitsCoordinates(app)
            if (app.LatitudeLimits(2) - app.LatitudeLimits(1) > 2) && (app.RegionDropDown.Value == "U.S.") 
                app.SLRprojectionButton.Enable = "off";
                app.TextArea.Visible = "on";
                app.TextArea.Value = 'For the U.S. territory, the area has been limited to a 2-degree side to avoid large amounts of data. You must reduce the selected area or select the worlwide option.';
            elseif (app.LatitudeLimits(2) - app.LatitudeLimits(1) > 6) && (app.RegionDropDown.Value == "Global") 
                app.SLRprojectionButton.Enable = "off";
                app.TextArea.Visible = "on";
                app.TextArea.Value = 'The area has been limited to a 6-degree side to avoid large amounts of data. You must reduce the selected area.';
            else
                app.SLRprojectionButton.Enable = "on";
                app.TextArea.Visible = "off";
                app.TextArea.Value = '';
            end
            app.Label.Text = '';
        end

        % Button pushed function: DownloadelevationdataButton
        function DownloadelevationdataButtonPushed(app, event)
            % This button is for confirming the download of terrain
            % elevation files for the selected area.      
            
            app.DownloadelevationdataButton.Enable = "off";       
            j = 0;
            for i = 1:app.downloadCounter
                if app.RegionDropDown.Value == "U.S."
                    try
                        websave(app.rasterFile(i,:), app.rasterURL(i,:));
                    catch
                        warning("No data available for area for " + string(app.coordinatesXY))
                        j = j + 1;
                    end
                    try
                        movefile(app.rasterFile(i,:), 'georasters\');
                    catch
                        warning("No file founded")
                    end
                else
                    try
                        websave(app.rasterFile(i,:), app.rasterURL(i,:),app.opt);
                    catch
                        warning("No data available for area for " + string(app.coordinatesXY))
                        delete(strcat(app.rasterFile(i,:),'.html'))
                        if exist("noData.mat","file")
                            tempNoData = open("noData.mat");
                            tempNoData = tempNoData.noData;
                            noData = [tempNoData; convertCharsToStrings(app.rasterFile(i,:))];
                            save noData.mat noData 
                            j = j + 1;
                        else
                            noData = convertCharsToStrings(app.rasterFile(i,:));
                            save noData.mat noData
                        end
                    end
                    try
                        movefile(app.rasterFile(i,:), 'georasters1\');
                    catch
                        warning("No file found")                    
                    end
                end
            end
            
            if j == app.downloadCounter && app.RegionDropDown.Value == "U.S."
                app.Label.Text = 'There is not elevation data for this area. Try Global region.';
            elseif j == app.downloadCounter && app.RegionDropDown.Value == "Global"
                app.Label.Text = 'There is not elevation data for this area.';
            else
                app.Label.Text = 'Download completed';
            end

            app.TextArea.Visible = "off";            
            clear app.opt
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.DownloadelevationdataButton.Enable = "on";
            usrn = app.UsernameEditField.Value;
            pswrd = app.PasswordEditField.Value;
            app.opt = weboptions("Username",usrn,"Password",pswrd);
            app.EarthdataPanel.Visible = "off";
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
            clear usrn pswrd
        end

        % Selection changed function: DatasetButtonGroup
        function DatasetButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatasetButtonGroup.SelectedObject;
            app.RegionDropDown.Value = '';
            RegionDropDownValueChanged(app,event)
            if selectedButton == app.NOAA2022ReportButton
                app.YearDropDown.Items = app.year_cell_array;
            elseif selectedButton == app.CopernicusButton
                app.YearDropDown.Items = string(2020:1:2050);    
                
            end
            app.TextArea.Visible = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel_5 = uipanel(app.UIFigure);
            app.Panel_5.Enable = 'off';
            app.YearDropDownLabel = uilabel(app.Panel_5);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.Panel_5);
            app.YearDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.YearDropDown.Value = '';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Enable = 'off';
            app.LongitudeLabel = uilabel(app.Panel_2);
            app.LongitudeLabel.Text = 'Longitude';
            app.MinSpinner_2Label = uilabel(app.Panel_2);
            app.MinSpinner_2Label.Text = 'Min';
            app.MinSpinner_2 = uispinner(app.Panel_2);
            app.MinSpinner_2.Step = 0.1;
            app.MinSpinner_2.Limits = [-360 360];
            app.MinSpinner_2.Value = -180;
            app.MaxSpinner_2Label = uilabel(app.Panel_2);
            app.MaxSpinner_2Label.Text = 'Max';
            app.MaxSpinner_2 = uispinner(app.Panel_2);
            app.MaxSpinner_2.Step = 0.1;
            app.MaxSpinner_2.Limits = [-360 360];
            app.MaxSpinner_2.Value = 180;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Enable = 'off';
            app.LatitudeLabel = uilabel(app.Panel);
            app.LatitudeLabel.Text = 'Latitude';
            app.MaxSpinnerLabel = uilabel(app.Panel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.Panel);
            app.MaxSpinner.Step = 0.1;
            app.MaxSpinner.Limits = [-89.1 90];
            app.MaxSpinner.Value = 90;
            app.MinSpinnerLabel = uilabel(app.Panel);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.Panel);
            app.MinSpinner.Step = 0.1;
            app.MinSpinner.Limits = [-90 89.9];
            app.MinSpinner.Value = -90;
            app.UpdatemapButton = uibutton(app.UIFigure, 'push');
            app.UpdatemapButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatemapButtonPushed, true);
            app.UpdatemapButton.Enable = 'off';
            app.UpdatemapButton.Text = 'Update map';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.ResetMapButton = uibutton(app.UIFigure, 'push');
            app.ResetMapButton.ButtonPushedFcn = createCallbackFcn(app, @ResetMapButtonPushed, true);
            app.ResetMapButton.Enable = 'off';
            app.ResetMapButton.Text = 'Reset Map';
            app.ManualCheckBox = uicheckbox(app.UIFigure);
            app.ManualCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualCheckBoxValueChanged, true);
            app.ManualCheckBox.Text = 'Manual';
            app.SeaLevelProjectionsLabel = uilabel(app.UIFigure);
            app.SeaLevelProjectionsLabel.Text = 'Sea Level Projections';
            app.SLRprojectionButton = uibutton(app.UIFigure, 'push');
            app.SLRprojectionButton.ButtonPushedFcn = createCallbackFcn(app, @SLRprojectionButtonPushed, true);
            app.SLRprojectionButton.Enable = 'off';
            app.SLRprojectionButton.Text = 'SLR projection';
            app.LimitsTextAreaLabel = uilabel(app.UIFigure);
            app.LimitsTextAreaLabel.Text = 'Limits';
            app.LimitsTextArea = uitextarea(app.UIFigure);
            app.LimitsTextArea.Editable = 'off';
            app.SelectthisareaButton = uibutton(app.UIFigure, 'push');
            app.SelectthisareaButton.ButtonPushedFcn = createCallbackFcn(app, @SelectthisareaButtonPushed, true);
            app.SelectthisareaButton.Enable = 'off';
            app.SelectthisareaButton.Text = 'Select this area';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DownloadelevationdataButton = uibutton(app.UIFigure, 'push');
            app.DownloadelevationdataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadelevationdataButtonPushed, true);
            app.DownloadelevationdataButton.Enable = 'off';
            app.DownloadelevationdataButton.Text = 'Download elevation data';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.EarthdataPanel = uipanel(app.UIFigure);
            app.EarthdataPanel.Title = 'Earthdata';
            app.EarthdataPanel.Visible = 'off';
            app.UsernameEditFieldLabel = uilabel(app.EarthdataPanel);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.EarthdataPanel, 'text');
            app.PasswordEditFieldLabel = uilabel(app.EarthdataPanel);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.EarthdataPanel, 'text');
            app.OkButton = uibutton(app.EarthdataPanel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.DatasetButtonGroup = uibuttongroup(app.UIFigure);
            app.DatasetButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatasetButtonGroupSelectionChanged, true);
            app.DatasetButtonGroup.Title = 'Dataset';
            app.NOAA2022ReportButton = uiradiobutton(app.DatasetButtonGroup);
            app.NOAA2022ReportButton.Text = 'NOAA 2022 Report';
            app.NOAA2022ReportButton.Value = true;
            app.CopernicusButton = uiradiobutton(app.DatasetButtonGroup);
            app.CopernicusButton.Text = 'Copernicus';
            app.GivenSeaLevelButton = uiradiobutton(app.DatasetButtonGroup);
            app.GivenSeaLevelButton.Text = 'Given Sea Level';
            app.RegionDropDownLabel = uilabel(app.UIFigure);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.UIFigure);
            app.RegionDropDown.Items = {'', 'U.S.', 'Global'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = '';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Enable = 'off';
            app.LocationDropDownLabel = uilabel(app.Panel_3);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.Panel_3);
            app.LocationDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.LocationDropDown.Value = '';
            app.Panel_4 = uipanel(app.UIFigure);
            app.Panel_4.Enable = 'off';
            app.ScenarioDropDownLabel = uilabel(app.Panel_4);
            app.ScenarioDropDownLabel.Text = 'Scenario';
            app.ScenarioDropDown = uidropdown(app.Panel_4);
            app.ScenarioDropDown.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.ScenarioDropDown.Value = '';
            app.Panel_6 = uipanel(app.UIFigure);
            app.Panel_6.Visible = 'off';
            app.SeaLevelmEditFieldLabel = uilabel(app.Panel_6);
            app.SeaLevelmEditFieldLabel.Text = 'Sea Level (m)';
            app.SeaLevelmEditField = uieditfield(app.Panel_6, 'numeric');
            app.SeaLevelmEditField.Limits = [0 3];
            app.SeaLevelmEditField.ValueDisplayFormat = '%.2f';
            app.CoastlinePredictionLabel = uilabel(app.UIFigure);
            app.CoastlinePredictionLabel.Text = 'Coastline Prediction';
            app.Version10Label = uilabel(app.UIFigure);
            app.Version10Label.Text = 'Version 1.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoastlinePrediction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end